classdef FLIR < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FLIRCameraAutomationUIFigure  matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        Image1Tab                     matlab.ui.container.Tab
        UIAxes_1                      matlab.ui.control.UIAxes
        Image2Tab                     matlab.ui.container.Tab
        UIAxes_2                      matlab.ui.control.UIAxes
        Image3Tab                     matlab.ui.container.Tab
        UIAxes_3                      matlab.ui.control.UIAxes
        Image4Tab                     matlab.ui.container.Tab
        UIAxes_4                      matlab.ui.control.UIAxes
        CompositeTab                  matlab.ui.container.Tab
        UIAxes_Composite              matlab.ui.control.UIAxes
        RefreshDevicesButton          matlab.ui.control.Button
        FLIRDeviceDropDown            matlab.ui.control.DropDown
        FLIRDeviceDropDownLabel       matlab.ui.control.Label
        AngleListListBox              matlab.ui.control.ListBox
        AngleListListBoxLabel         matlab.ui.control.Label
        OpenConnectiontoSMC100Button  matlab.ui.control.Button
        RotationDeviceDropDown        matlab.ui.control.DropDown
        RotationDeviceDropDownLabel   matlab.ui.control.Label
        OpenConnectiontoFLIRCameraButton  matlab.ui.control.Button
        BeginImageCollectionButton    matlab.ui.control.Button
        ChooseImageDirectoryButton    matlab.ui.control.Button
        ExcluderotationanglesandextensionsLabel  matlab.ui.control.Label
        FileNamesEditField            matlab.ui.control.EditField
        FileNamesEditFieldLabel       matlab.ui.control.Label
    end

    
    properties (Access = private)
        FLIR_obj; % FLIR Camera Object
        image_dir;
        SMC_obj;
    end
    
    methods (Access = private)
        
        function moveSMC(app, angle)
            cmd = string(['1PA' num2str(angle)]);
            writeline(app.SMC_obj, cmd);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            info = imaqhwinfo;
            list = info.InstalledAdaptors;

            ports = serialportlist;

            if ~isempty(ports)
               app.RotationDeviceDropDown.Items = ports; 
               app.OpenConnectiontoSMC100Button.Enable = "on";
            end
            if ~isempty(list)
                app.FLIRDeviceDropDown.Items = list;
                app.OpenConnectiontoFLIRCameraButton.Enable = "on";
            end

            if isfile("config.mat")
                data = load("config.mat");
                app.image_dir = data(1).filepath;
            else
                noDlg = questdlg("You do not have a config file saved in the current directory, would you like to create one?");
                if noDlg == "Yes"
                    filepath = uigetdir("./", "Choose a folder to save images to");
                    if path ~= 0
                        save("config.mat", "filepath", "-mat");
                        app.image_dir = filepath;
                    end
                end
            end
        end

        % Button pushed function: OpenConnectiontoFLIRCameraButton
        function OpenConnectiontoFLIRCameraButtonPushed(app, event)
            app.FLIR_obj = videoinput(app.FLIRDeviceDropDown.Value, 1, "Mono16");
            src = getselectedsource(app.FLIR_obj);
            src.PacketSize = 1484;
            app.OpenConnectiontoFLIRCameraButton.Text = "Close Connection to FLIR Camera";
            app.BeginImageCollectionButton.Enable = "on";
        end

        % Button pushed function: ChooseImageDirectoryButton
        function ChooseImageDirectoryButtonPushed(app, event)
            app.image_dir = uigetdir();
        end

        % Button pushed function: BeginImageCollectionButton
        function BeginImageCollectionButtonPushed(app, event)
            % If the user does not have a directory set, but they do have a file name set, let the user select a folder
            % If the user has both fields populated, skip straight to
            % saving the image. If the user only has one of the two, have
            % them select the other.

            if isempty(app.SMC_obj) || app.SMC_obj == 0
                errordlg("Please connect to the rotation device before you start collecting images.");
                return;
            end
            
            if isempty(app.image_dir) && app.FileNamesEditField.Value == ""
                [fileName, selectedFolder] = uiputfile("*.mat", "Select a directory and file name", "FLIRImage.mat");
                if fileName == 0
                    return;
                end
                app.image_dir = selectedFolder;
                app.FileNamesEditField.Value = fileName;
            elseif isempty(app.image_dir) && app.FileNamesEditField.Value ~= ""
                selectedFolder = uigetdir();
                if selectedFolder == 0
                    return;
                end
                app.image_dir = selectedFolder;
            elseif ~isempty(app.image_dir) && app.FileNamesEditField.Value == ""
                fileName = inputdlg("Please define a file name, excluding the rotation stages and file extension.", "Define file name");
                if isempty(fileName)
                    return;
                end
                app.FileNamesEditField.Value = fileName{1};
            end

            image1Path = strcat(app.image_dir, "\", app.FileNamesEditField.Value, "_0.png");
            image2Path = strcat(app.image_dir, "\", app.FileNamesEditField.Value, "_45.png");
            image3Path = strcat(app.image_dir, "\", app.FileNamesEditField.Value, "_90.png");
            image4Path = strcat(app.image_dir, "\", app.FileNamesEditField.Value, "_135.png");

            moveSMC(app, 0);
            pause(3);
            [snapshot1, metadata] = getsnapshot(app.FLIR_obj);
            moveSMC(app, 45);
            pause(1);
            [snapshot2, metadata] = getsnapshot(app.FLIR_obj);
            moveSMC(app, 90);
            pause(1);
            [snapshot3, metadata] = getsnapshot(app.FLIR_obj);
            moveSMC(app, 135);
            [snapshot4, metadata] = getsnapshot(app.FLIR_obj);

%             imagesc(app.UIAxes, snapshot1);
%             figure;
%             imtool(snapshot1);
%             figure;
%             imtool(snapshot2);
%             figure
%             imtool(snapshot3);
%             figure;
%             imtool(snapshot4);
            images.im0 = snapshot1;
            images.im45 = snapshot2;
            images.im90 = snapshot3;
            images.im135 = snapshot4;

            imagesc(app.UIAxes, snapshot1);
            imagesc(app.UIAxes_2, snapshot2);
            imagesc(app.UIAxes_3, snapshot3);
            imagesc(app.UIAxes_4, snapshot4);
            % The composite image graph is called "app.UIAxes_Composite"
            save("images_aligned.mat", "images", "-mat");
        end

        % Button pushed function: OpenConnectiontoSMC100Button
        function OpenConnectiontoSMC100ButtonPushed(app, event)
            app.SMC_obj = serialport(app.RotationDeviceDropDown.Value, 57600);
            configureTerminator(app.SMC_obj, "CR/LF");
            writeline(app.SMC_obj, "1OR");
            app.OpenConnectiontoSMC100Button.Text = "Close Connection to SMC 100";
        end

        % Button pushed function: RefreshDevicesButton
        function RefreshDevicesButtonPushed(app, event)
            startupFcn(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FLIRCameraAutomationUIFigure = uifigure('Visible', 'off');
            app.FLIRCameraAutomationUIFigure.Name = 'FLIR Camera Automation';
            app.FileNamesEditFieldLabel = uilabel(app.FLIRCameraAutomationUIFigure);
            app.FileNamesEditFieldLabel.Text = 'File Names';
            app.FileNamesEditField = uieditfield(app.FLIRCameraAutomationUIFigure, 'text');
            app.ExcluderotationanglesandextensionsLabel = uilabel(app.FLIRCameraAutomationUIFigure);
            app.ExcluderotationanglesandextensionsLabel.Text = 'Exclude rotation angles and extensions';
            app.ChooseImageDirectoryButton = uibutton(app.FLIRCameraAutomationUIFigure, 'push');
            app.ChooseImageDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseImageDirectoryButtonPushed, true);
            app.ChooseImageDirectoryButton.Text = 'Choose Image Directory';
            app.BeginImageCollectionButton = uibutton(app.FLIRCameraAutomationUIFigure, 'push');
            app.BeginImageCollectionButton.ButtonPushedFcn = createCallbackFcn(app, @BeginImageCollectionButtonPushed, true);
            app.BeginImageCollectionButton.Enable = 'off';
            app.BeginImageCollectionButton.Text = 'Begin Image Collection';
            app.OpenConnectiontoFLIRCameraButton = uibutton(app.FLIRCameraAutomationUIFigure, 'push');
            app.OpenConnectiontoFLIRCameraButton.ButtonPushedFcn = createCallbackFcn(app, @OpenConnectiontoFLIRCameraButtonPushed, true);
            app.OpenConnectiontoFLIRCameraButton.Enable = 'off';
            app.OpenConnectiontoFLIRCameraButton.Text = 'Open Connection to FLIR Camera';
            app.RotationDeviceDropDownLabel = uilabel(app.FLIRCameraAutomationUIFigure);
            app.RotationDeviceDropDownLabel.Text = 'Rotation Device';
            app.RotationDeviceDropDown = uidropdown(app.FLIRCameraAutomationUIFigure);
            app.RotationDeviceDropDown.Items = {};
            app.RotationDeviceDropDown.Value = {};
            app.OpenConnectiontoSMC100Button = uibutton(app.FLIRCameraAutomationUIFigure, 'push');
            app.OpenConnectiontoSMC100Button.ButtonPushedFcn = createCallbackFcn(app, @OpenConnectiontoSMC100ButtonPushed, true);
            app.OpenConnectiontoSMC100Button.Enable = 'off';
            app.OpenConnectiontoSMC100Button.Text = 'Open Connection to SMC100';
            app.AngleListListBoxLabel = uilabel(app.FLIRCameraAutomationUIFigure);
            app.AngleListListBoxLabel.Text = 'Angle List';
            app.AngleListListBox = uilistbox(app.FLIRCameraAutomationUIFigure);
            app.AngleListListBox.Items = {'0', '45', '90', '135'};
            app.AngleListListBox.Value = '0';
            app.FLIRDeviceDropDownLabel = uilabel(app.FLIRCameraAutomationUIFigure);
            app.FLIRDeviceDropDownLabel.Text = 'FLIR Device';
            app.FLIRDeviceDropDown = uidropdown(app.FLIRCameraAutomationUIFigure);
            app.FLIRDeviceDropDown.Items = {};
            app.FLIRDeviceDropDown.Value = {};
            app.RefreshDevicesButton = uibutton(app.FLIRCameraAutomationUIFigure, 'push');
            app.RefreshDevicesButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshDevicesButtonPushed, true);
            app.RefreshDevicesButton.Text = 'Refresh Devices';
            app.TabGroup = uitabgroup(app.FLIRCameraAutomationUIFigure);
            app.Image1Tab = uitab(app.TabGroup);
            app.Image1Tab.Title = 'Image 1';
            app.UIAxes_1 = uiaxes(app.Image1Tab);
            title(app.UIAxes_1, 'Image 1 (0 deg. rotation)')
            xlabel(app.UIAxes_1, 'X')
            ylabel(app.UIAxes_1, 'Y')
            zlabel(app.UIAxes_1, 'Z')
            app.Image2Tab = uitab(app.TabGroup);
            app.Image2Tab.Title = 'Image 2';
            app.UIAxes_2 = uiaxes(app.Image2Tab);
            title(app.UIAxes_2, 'Image 2 (45 deg. rotation)')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.Image3Tab = uitab(app.TabGroup);
            app.Image3Tab.Title = 'Image 3';
            app.UIAxes_3 = uiaxes(app.Image3Tab);
            title(app.UIAxes_3, 'Image 3 (90 deg. rotation)')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.Image4Tab = uitab(app.TabGroup);
            app.Image4Tab.Title = 'Image 4';
            app.UIAxes_4 = uiaxes(app.Image4Tab);
            title(app.UIAxes_4, 'Image 4 (135 deg. rotation)')
            xlabel(app.UIAxes_4, 'X')
            ylabel(app.UIAxes_4, 'Y')
            zlabel(app.UIAxes_4, 'Z')
            app.CompositeTab = uitab(app.TabGroup);
            app.CompositeTab.Title = 'Composite';
            app.UIAxes_Composite = uiaxes(app.CompositeTab);
            title(app.UIAxes_Composite, 'Composite Image')
            xlabel(app.UIAxes_Composite, 'X')
            ylabel(app.UIAxes_Composite, 'Y')
            zlabel(app.UIAxes_Composite, 'Z')
            % Show the figure after all components are created
            app.FLIRCameraAutomationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FLIR

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FLIRCameraAutomationUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FLIRCameraAutomationUIFigure)
        end
    end
end