classdef QPT90Controller < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        QPT90ControllerUIFigure        matlab.ui.Figure
        FileMenu                       matlab.ui.container.Menu
        LoadAngleFileMenu              matlab.ui.container.Menu
        ShortcutsMenu                  matlab.ui.container.Menu
        EmergencyStopMenu              matlab.ui.container.Menu
        GotoDeltaCoordinatesMenu       matlab.ui.container.Menu
        GotoHomeMenu                   matlab.ui.container.Menu
        GotoAbsolute0Menu              matlab.ui.container.Menu
        RefreshButton                  matlab.ui.control.Button
        OpenPortButton                 matlab.ui.control.StateButton
        STOPButton                     matlab.ui.control.Button
        SelectCOMPortDropDown          matlab.ui.control.DropDown
        SelectCOMPortDropDownLabel     matlab.ui.control.Label
        TiltValue                      matlab.ui.control.EditField
        TiltLabel                      matlab.ui.control.Label
        PanValue                       matlab.ui.control.EditField
        PanEditFieldLabel              matlab.ui.control.Label
        TiltGauge                      matlab.ui.control.SemicircularGauge
        TiltValueLabel                 matlab.ui.control.Label
        PanValueLabel                  matlab.ui.control.Label
        PanGauge                       matlab.ui.control.Gauge
        TabGroup                       matlab.ui.container.TabGroup
        PositionsTab                   matlab.ui.container.Tab
        GotoHomeButton                 matlab.ui.control.Button
        GotoAbsolute0Button            matlab.ui.control.Button
        SetHomeButton                  matlab.ui.control.Button
        OverwriteLimitsCheckBox        matlab.ui.control.CheckBox
        SoftLimitsLabel                matlab.ui.control.Label
        Pan_Number                     matlab.ui.control.NumericEditField
        Tilt_Number                    matlab.ui.control.NumericEditField
        PanLabel                       matlab.ui.control.Label
        TiltLabel_2                    matlab.ui.control.Label
        GotoAbsoluteCoordinatesButton  matlab.ui.control.Button
        GotoDeltaCoordinatesButton     matlab.ui.control.Button
        SetCWLimitButton               matlab.ui.control.Button
        SetCCWLimitButton              matlab.ui.control.Button
        SetUPLimitButton               matlab.ui.control.Button
        SetDOWNLimitButton             matlab.ui.control.Button
        AutomationTab                  matlab.ui.container.Tab
        LoadFileButton                 matlab.ui.control.Button
        GotoSelectedCoordinatesButton  matlab.ui.control.Button
        ListBox                        matlab.ui.control.ListBox
    end

    
    properties (Access = private)
        executing = false; % Description
        device;
        t; % Timer object

        overwriteSoft = false;
        stop = false;
        reset = false;
        stopTimer;

        cwLimit;
        ccwLimit;
        upLimit;
        downLimit;

        cwLimitCount;
    end
    
    methods (Access = private)
        function GetDeviceStatus(app, ~, ~)
            if app.stop
                if toc(app.stopTimer) > 2
                    app.stop = false;
                end
            end

            [pan, tilt] = getStatus(app.device, app.overwriteSoft, app.stop, app.reset);
            if (pan == 1000)
                return;
            end
            app.PanValue.Value = num2str(pan);
            app.TiltValue.Value = num2str(tilt);
            app.PanGauge.Value = pan;
            app.TiltGauge.Value = tilt;

            app.PanGauge.ScaleColorLimits = [-180 app.ccwLimit; app.cwLimit 180];
            app.TiltGauge.ScaleColorLimits = [-90 app.downLimit; app.upLimit 90];
        end
    end

    methods (Access = public)
        function ChangeValue(app, val)
            app.Pan_Number.Value = val;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
              app.SelectCOMPortDropDown.Items = {};
              app.STOPButton.Enable = "off";
              app.GotoAbsoluteCoordinatesButton.Enable = "off";
              app.GotoDeltaCoordinatesButton.Enable = "off";
              app.SetHomeButton.Enable = "off";
              app.GotoHomeButton.Enable = "off";
              app.GotoAbsolute0Button.Enable = "off";
              app.SetCCWLimitButton.Enable = "off";
              app.SetCWLimitButton.Enable = "off";
              app.SetUPLimitButton.Enable = "off";
              app.SetDOWNLimitButton.Enable = "off";
              app.OverwriteLimitsCheckBox.Enable = "off";
              app.Pan_Number.Enable = "off";
              app.Tilt_Number.Enable = "off";
              app.ListBox.Enable = "off";
              app.GotoSelectedCoordinatesButton.Enable = "off";
              ports = serialportlist;

              app.PanGauge.ScaleColors = ["#FFA700", "#FFA700"];
              app.TiltGauge.ScaleColors = ["#FFA700", "#FFA700"];

              if (~isempty(ports))
%                 for i = 1:length(ports)
%                     app.SelectCOMPortDropDown.Items = [app.SelectCOMPortDropDown.Items ports(i)];
%                 end
                app.SelectCOMPortDropDown.Items = ports;
                app.OpenPortButton.Enable = "on";
              else
                  app.OpenPortButton.Enable = "off";
                  app.STOPButton.Enable = "off";
                  app.GotoAbsoluteCoordinatesButton.Enable = "off";
                  app.GotoDeltaCoordinatesButton.Enable = "off";
                  app.SetHomeButton.Enable = "off";
                  app.GotoHomeButton.Enable = "off";
                  app.GotoAbsolute0Button.Enable = "off";
                  app.SetCCWLimitButton.Enable = "off";
                  app.SetCWLimitButton.Enable = "off";
                  app.SetUPLimitButton.Enable = "off";
                  app.SetDOWNLimitButton.Enable = "off";
                  app.OverwriteLimitsCheckBox.Enable = "off";
                  app.Pan_Number.Enable = "off";
                  app.Tilt_Number.Enable = "off";
                  app.ListBox.Enable = "off";
                  app.GotoSelectedCoordinatesButton.Enable = "off";
                  errordlg("No COM ports detected.", "No COM Ports");
              end
        end

        % Button pushed function: GotoAbsoluteCoordinatesButton
        function GotoAbsoluteCoordinatesButtonPushed(app, event)
            try
                movetocoords(app.device, [app.Pan_Number.Value, app.Tilt_Number.Value])
            catch
                errordlg("Error reading back from the device.");
            end
        end

        % Button pushed function: GotoDeltaCoordinatesButton
        function GotoDeltaCoordinatesButtonPushed(app, event)
            try
                movedelta(app.device, [app.Pan_Number.Value, app.Tilt_Number.Value]);
            catch
                errordlg("Error reading back from the device.");
            end
        end

        % Callback function
        function SetHomeCoordinatesButtonPushed(app, event)
            sethome(app.device);
        end

        % Value changed function: OpenPortButton
        function OpenPortButtonValueChanged(app, event)
            if (isempty(app.device) || app.device == 0)
                f = waitbar(0, "Initializing...");
                app.device = serialport(app.SelectCOMPortDropDown.Value, 57600);
                app.device.Timeout = .5;
                
                app.OpenPortButton.Text = "Syncing Autobaud...";
                waitbar(.33, f);
                for i = 1:20
                        [pan, tilt] = getStatus(app.device, false, false, false);
                    if (pan ~= 1000 && tilt ~= 1000)
                        break;
                    end
                    if (i == 20)
                        errordlg("Error connecting to device", "Could not Connect");
                        app.OpenPortButton.Value = 0;
                        app.OpenPortButton.Text = "Open Port";
                        app.device = 0;
                        close(f);
                        return;
                    end
                end                 

                app.OpenPortButton.Value = 1;
                waitbar(.66, f);
                app.OpenPortButton.Text = "Getting Soft Limits...";

                % Get all the values needed to populate the GUI
                [app.cwLimit, app.ccwLimit, app.upLimit, app.downLimit] = readLimits(app.device);
                app.PanGauge.ScaleColorLimits = [-180 app.ccwLimit; app.cwLimit 180];
                app.TiltGauge.ScaleColorLimits = [-90 app.downLimit; app.upLimit 90];

                app.STOPButton.Enable = "on";
                app.GotoAbsoluteCoordinatesButton.Enable = "on";
                app.GotoDeltaCoordinatesButton.Enable = "on";
                app.SetHomeButton.Enable = "on";
                app.GotoHomeButton.Enable = "on";
                app.GotoAbsolute0Button.Enable = "on";
                app.SetCCWLimitButton.Enable = "on";
                app.SetCWLimitButton.Enable = "on";
                app.SetUPLimitButton.Enable = "on";
                app.SetDOWNLimitButton.Enable = "on";
                app.OverwriteLimitsCheckBox.Enable = "on";
                app.Pan_Number.Enable = "on";
                app.Tilt_Number.Enable = "on";
                app.ListBox.Enable = "on";
                app.GotoSelectedCoordinatesButton.Enable = "on";

                app.OpenPortButton.Text = "Close Port";
                waitbar(1, f);
                close(f);

                % Begin a timer to read the data every second
                app.t = timer('Name',"keepalive",'BusyMode',"queue",'TimerFcn',@app.GetDeviceStatus,'ExecutionMode',"fixedRate");
                app.t.Period = .1;
                start(app.t);
            else
                app.device = 0;
                app.OpenPortButton.Value = 0;
                app.OpenPortButton.Text = "Open Port";
            end
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
            app.stop = true;
            app.stopTimer = tic;
        end

        % Value changed function: OverwriteLimitsCheckBox
        function OverwriteLimitsCheckBoxValueChanged(app, event)
            app.overwriteSoft = app.OverwriteLimitsCheckBox.Value;
        end

        % Button pushed function: SetCWLimitButton
        function SetCWLimitButtonPushed(app, event)
            stop(app.t);
            flush(app.device);
            setSoft(app.device, 0);
            count = 0;
            newLimit = 1000;
            while newLimit ~= str2double(app.PanValue.Value)
                count = count + 1;
                if (count == 100)
                   errordlg("Could not set limits");
                   return;
                end
                [newLimit, ~, ~, ~] = readLimits(app.device);
                pause(0.05);
            end
            app.cwLimit = newLimit;
            start(app.t);
        end

        % Button pushed function: SetHomeButton
        function SetHomeButtonPushed(app, event)
            sethome(app.device);
        end

        % Button pushed function: GotoHomeButton
        function GotoHomeButtonPushed(app, event)
            movetohome(app.device);
        end

        % Menu selected function: EmergencyStopMenu
        function EmergencyStopMenuSelected(app, event)
            STOPButtonPushed(app);
        end

        % Button pushed function: GotoSelectedCoordinatesButton
        function GotoSelectedCoordinatesButtonPushed(app, event)
            value = app.ListBox.Value;
            val2 = app.ListBox.Items(value(1:end));
            val2 = val2{1};

            xy = strsplit(val2, ", ");

            movetocoords(app.device, [str2num(xy{1}), str2num(xy{2})]);

            if value == length(app.ListBox.Items)
                value = 0;
            end
            app.ListBox.Value = value + 1;
        end

        % Button pushed function: LoadFileButton
        function LoadFileButtonPushed(app, event)
            [file, path] = uigetfile("*.csv");
            if file == 0
                return;
            end

            try
                combos = csvread(strcat(path, "\" ,file));
            catch
                errordlg("Could not load this file. This may be because one or more values are not integers.");
                return;
            end
            
            if width(combos) ~= 2
                errordlg("The selected file must be contain coordinate pairs specified in two columns.");
                return;
            end

            app.ListBox.Items = {};
            app.ListBox.ItemsData = {};

            for i = 1:length(combos)
                value = strcat(num2str(combos(i, 1)), ", ", num2str(combos(i, 2)));
                if combos(i, 1) > 180 || combos(i, 2) > 90 || combos(i, 1) < -180 || combos(i, 2) < -90
                   errordlg(strcat("Skipped coordinate pair on line ", num2str(i), " because the coordinates on this line exceeded the boundaries of the device."));
                   continue;
                end
                app.ListBox.Items = [app.ListBox.Items value];
                app.ListBox.ItemsData = [app.ListBox.ItemsData i];
            end
        end

        % Menu selected function: LoadAngleFileMenu
        function LoadAngleFileMenuSelected(app, event)
            LoadFileButtonPushed(app);
        end

        % Close request function: QPT90ControllerUIFigure
        function QPT90ControllerUIFigureCloseRequest(app, event)
            STOPButtonPushed(app);
            delete(app)
        end

        % Button pushed function: GotoAbsolute0Button
        function GotoAbsolute0ButtonPushed(app, event)
            movetozero(app.device);
        end

        % Button pushed function: SetCCWLimitButton
        function SetCCWLimitButtonPushed(app, event)
            stop(app.t);
            flush(app.device);
            setSoft(app.device, 1);
            count = 0;
            newLimit = 1000;
            while newLimit ~= str2double(app.PanValue.Value)
                count = count + 1;
                if (count == 100)
                   errordlg("Could not set limits");
                   return;
                end
                [~, newLimit, ~, ~] = readLimits(app.device);
                pause(0.05);
            end
            app.ccwLimit = newLimit;
            start(app.t);
        end

        % Button pushed function: SetUPLimitButton
        function SetUPLimitButtonPushed(app, event)
            stop(app.t);
            flush(app.device);
            setSoft(app.device, 2);
            count = 0;
            newLimit = 1000;
            while newLimit ~= str2double(app.TiltValue.Value)
                count = count + 1;
                if (count == 100)
                   errordlg("Could not set limits");
                   return;
                end
                [~, ~, newLimit, ~] = readLimits(app.device);
                pause(0.05);
            end
            app.upLimit = newLimit;
            start(app.t);
        end

        % Button pushed function: SetDOWNLimitButton
        function SetDOWNLimitButtonPushed(app, event)
            stop(app.t);
            flush(app.device);
            setSoft(app.device, 3);
            count = 0;
            newLimit = 1000;
            while newLimit ~= str2double(app.TiltValue.Value)
                count = count + 1;
                if (count == 100)
                   errordlg("Could not set limits");
                   return;
                end
                [~, ~, ~, newLimit] = readLimits(app.device);
                pause(0.05);
            end
            app.downLimit = newLimit;
            start(app.t);
        end

        % Callback function
        function GotoAbsoluteCoordinatesMenuSelected(app, event)
            GotoAbsoluteCoordinatesButtonPushed(app);
        end

        % Menu selected function: GotoDeltaCoordinatesMenu
        function GotoDeltaCoordinatesMenuSelected(app, event)
            GotoDeltaCoordinatesButtonPushed(app);
        end

        % Menu selected function: GotoHomeMenu
        function GotoHomeMenuSelected(app, event)
            GotoHomeButtonPushed(app);
        end

        % Menu selected function: GotoAbsolute0Menu
        function GotoAbsolute0MenuSelected(app, event)
            GotoAbsolute0ButtonPushed(app);
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            startupFcn(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.QPT90ControllerUIFigure = uifigure('Visible', 'off');
            app.QPT90ControllerUIFigure.Name = 'QPT90 Controller';
            app.QPT90ControllerUIFigure.CloseRequestFcn = createCallbackFcn(app, @QPT90ControllerUIFigureCloseRequest, true);
            app.FileMenu = uimenu(app.QPT90ControllerUIFigure);
            app.FileMenu.Text = 'File';
            app.LoadAngleFileMenu = uimenu(app.FileMenu);
            app.LoadAngleFileMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadAngleFileMenuSelected, true);
            app.LoadAngleFileMenu.Text = 'Load Angle File';
            app.ShortcutsMenu = uimenu(app.QPT90ControllerUIFigure);
            app.ShortcutsMenu.Text = 'Shortcuts';
            app.EmergencyStopMenu = uimenu(app.ShortcutsMenu);
            app.EmergencyStopMenu.MenuSelectedFcn = createCallbackFcn(app, @EmergencyStopMenuSelected, true);
            app.EmergencyStopMenu.Accelerator = 'S';
            app.EmergencyStopMenu.Text = 'Emergency Stop';
            app.GotoDeltaCoordinatesMenu = uimenu(app.ShortcutsMenu);
            app.GotoDeltaCoordinatesMenu.MenuSelectedFcn = createCallbackFcn(app, @GotoDeltaCoordinatesMenuSelected, true);
            app.GotoDeltaCoordinatesMenu.Accelerator = 'D';
            app.GotoDeltaCoordinatesMenu.Text = 'Go to Delta Coordinates';
            app.GotoHomeMenu = uimenu(app.ShortcutsMenu);
            app.GotoHomeMenu.MenuSelectedFcn = createCallbackFcn(app, @GotoHomeMenuSelected, true);
            app.GotoHomeMenu.Accelerator = 'H';
            app.GotoHomeMenu.Text = 'Go to Home';
            app.GotoAbsolute0Menu = uimenu(app.ShortcutsMenu);
            app.GotoAbsolute0Menu.MenuSelectedFcn = createCallbackFcn(app, @GotoAbsolute0MenuSelected, true);
            app.GotoAbsolute0Menu.Accelerator = '0';
            app.GotoAbsolute0Menu.Text = 'Go to Absolute 0';
            app.TabGroup = uitabgroup(app.QPT90ControllerUIFigure);
            app.PositionsTab = uitab(app.TabGroup);
            app.PositionsTab.Title = 'Positions';
            app.SetDOWNLimitButton = uibutton(app.PositionsTab, 'push');
            app.SetDOWNLimitButton.ButtonPushedFcn = createCallbackFcn(app, @SetDOWNLimitButtonPushed, true);
            app.SetDOWNLimitButton.Tooltip = {'Sets the downwards limit to the current position.'};
            app.SetDOWNLimitButton.Text = 'Set DOWN Limit';
            app.SetUPLimitButton = uibutton(app.PositionsTab, 'push');
            app.SetUPLimitButton.ButtonPushedFcn = createCallbackFcn(app, @SetUPLimitButtonPushed, true);
            app.SetUPLimitButton.Text = 'Set UP Limit';
            app.SetCCWLimitButton = uibutton(app.PositionsTab, 'push');
            app.SetCCWLimitButton.ButtonPushedFcn = createCallbackFcn(app, @SetCCWLimitButtonPushed, true);
            app.SetCCWLimitButton.Tooltip = {'Sets the counter-clockwise limit to the current position. Counter-clockwise corresponds to negative pan values.'};
            app.SetCCWLimitButton.Text = 'Set CCW Limit';
            app.SetCWLimitButton = uibutton(app.PositionsTab, 'push');
            app.SetCWLimitButton.ButtonPushedFcn = createCallbackFcn(app, @SetCWLimitButtonPushed, true);
            app.SetCWLimitButton.Tooltip = {'Sets the clockwise limit to the current position. Clockwise corresponds to positive pan vales.'};
            app.SetCWLimitButton.Text = 'Set CW Limit';
            app.GotoDeltaCoordinatesButton = uibutton(app.PositionsTab, 'push');
            app.GotoDeltaCoordinatesButton.ButtonPushedFcn = createCallbackFcn(app, @GotoDeltaCoordinatesButtonPushed, true);
            app.GotoDeltaCoordinatesButton.Text = 'Go to Delta Coordinates';
            app.GotoAbsoluteCoordinatesButton = uibutton(app.PositionsTab, 'push');
            app.GotoAbsoluteCoordinatesButton.ButtonPushedFcn = createCallbackFcn(app, @GotoAbsoluteCoordinatesButtonPushed, true);
            app.GotoAbsoluteCoordinatesButton.Text = 'Go to Absolute Coordinates';
            app.TiltLabel_2 = uilabel(app.PositionsTab);
            app.TiltLabel_2.Text = 'Tilt';
            app.PanLabel = uilabel(app.PositionsTab);
            app.PanLabel.Text = 'Pan';
            app.Tilt_Number = uieditfield(app.PositionsTab, 'numeric');
            app.Tilt_Number.Limits = [-90 90];
            app.Pan_Number = uieditfield(app.PositionsTab, 'numeric');
            app.Pan_Number.Limits = [-180 180];
            app.SoftLimitsLabel = uilabel(app.PositionsTab);
            app.SoftLimitsLabel.Text = 'Soft Limits';
            app.OverwriteLimitsCheckBox = uicheckbox(app.PositionsTab);
            app.OverwriteLimitsCheckBox.ValueChangedFcn = createCallbackFcn(app, @OverwriteLimitsCheckBoxValueChanged, true);
            app.OverwriteLimitsCheckBox.Text = 'Overwrite Limits?';
            app.SetHomeButton = uibutton(app.PositionsTab, 'push');
            app.SetHomeButton.ButtonPushedFcn = createCallbackFcn(app, @SetHomeButtonPushed, true);
            app.SetHomeButton.Tooltip = {'Sets the upwards limit to the current position.'};
            app.SetHomeButton.Text = 'Set Home';
            app.GotoAbsolute0Button = uibutton(app.PositionsTab, 'push');
            app.GotoAbsolute0Button.ButtonPushedFcn = createCallbackFcn(app, @GotoAbsolute0ButtonPushed, true);
            app.GotoAbsolute0Button.Text = 'Go to Absolute 0';
            app.GotoHomeButton = uibutton(app.PositionsTab, 'push');
            app.GotoHomeButton.ButtonPushedFcn = createCallbackFcn(app, @GotoHomeButtonPushed, true);
            app.GotoHomeButton.Text = 'Go to Home';
            app.AutomationTab = uitab(app.TabGroup);
            app.AutomationTab.Title = 'Automation';
            app.ListBox = uilistbox(app.AutomationTab);
            app.ListBox.Items = {};
            app.ListBox.Value = {};
            app.GotoSelectedCoordinatesButton = uibutton(app.AutomationTab, 'push');
            app.GotoSelectedCoordinatesButton.ButtonPushedFcn = createCallbackFcn(app, @GotoSelectedCoordinatesButtonPushed, true);
            app.GotoSelectedCoordinatesButton.Text = 'Go to Selected Coordinates';
            app.LoadFileButton = uibutton(app.AutomationTab, 'push');
            app.LoadFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFileButtonPushed, true);
            app.LoadFileButton.Text = 'Load File';
            app.PanGauge = uigauge(app.QPT90ControllerUIFigure, 'circular');
            app.PanGauge.Limits = [-180 180];
            app.PanGauge.MajorTicks = [-180 -135 -90 -45 0 45 90 135 180];
            app.PanGauge.MinorTicks = [-175 -170 -165 -160 -155 -150 -145 -140 -130 -125 -120 -115 -110 -105 -100 -95 -85 -80 -75 -70 -65 -60 -55 -50 -40 -35 -30 -25 -20 -15 -10 -5 5 10 15 20 25 30 35 40 50 55 60 65 70 75 80 85 95 100 105 110 115 120 125 130 140 145 150 155 160 165 170 175];
            app.PanValueLabel = uilabel(app.QPT90ControllerUIFigure);
            app.PanValueLabel.Text = 'Pan Value';
            app.TiltValueLabel = uilabel(app.QPT90ControllerUIFigure);
            app.TiltValueLabel.Text = 'Tilt Value';
            app.TiltGauge = uigauge(app.QPT90ControllerUIFigure, 'semicircular');
            app.TiltGauge.Limits = [-90 90];
            app.TiltGauge.MajorTicks = [-90 -60 -30 0 30 60 90];
            app.TiltGauge.ScaleDirection = 'counterclockwise';
            app.TiltGauge.MinorTicks = [-85 -80 -75 -70 -65 -55 -50 -45 -40 -35 -25 -20 -15 -10 -5 5 10 15 20 25 35 40 45 50 55 65 70 75 80 85];
            app.PanEditFieldLabel = uilabel(app.QPT90ControllerUIFigure);
            app.PanEditFieldLabel.Text = 'Pan';
            app.PanValue = uieditfield(app.QPT90ControllerUIFigure, 'text');
            app.PanValue.Editable = 'off';
            app.PanValue.Value = '0';
            app.TiltLabel = uilabel(app.QPT90ControllerUIFigure);
            app.TiltLabel.Text = 'Tilt';
            app.TiltValue = uieditfield(app.QPT90ControllerUIFigure, 'text');
            app.TiltValue.Editable = 'off';
            app.TiltValue.Value = '0';
            app.SelectCOMPortDropDownLabel = uilabel(app.QPT90ControllerUIFigure);
            app.SelectCOMPortDropDownLabel.Text = 'Select COM Port';
            app.SelectCOMPortDropDown = uidropdown(app.QPT90ControllerUIFigure);
            app.SelectCOMPortDropDown.Items = {};
            app.SelectCOMPortDropDown.Value = {};
            app.STOPButton = uibutton(app.QPT90ControllerUIFigure, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Text = 'STOP';
            app.OpenPortButton = uibutton(app.QPT90ControllerUIFigure, 'state');
            app.OpenPortButton.ValueChangedFcn = createCallbackFcn(app, @OpenPortButtonValueChanged, true);
            app.OpenPortButton.Text = 'Open Port';
            app.RefreshButton = uibutton(app.QPT90ControllerUIFigure, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            % Show the figure after all components are created
            app.QPT90ControllerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = QPT90Controller

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.QPT90ControllerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.QPT90ControllerUIFigure)
        end
    end
end