classdef lasercontrol < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SpectraPhysicsJ20LaserUIFigure  matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        SaveconfigurationMenu           matlab.ui.container.Menu
        LoadconfigurationMenu           matlab.ui.container.Menu
        ProgramUpdatesPanel             matlab.ui.container.Panel
        Updates                         matlab.ui.control.TextArea
        TabGroup                        matlab.ui.container.TabGroup
        MainControlsTab                 matlab.ui.container.Tab
        DiodeDataPanel                  matlab.ui.container.Panel
        Lamp                            matlab.ui.control.Lamp
        SetValuesPanel                  matlab.ui.container.Panel
        RepRateEditField                matlab.ui.control.NumericEditField
        RepRateEditFieldLabel           matlab.ui.control.Label
        UpdateValuesButton              matlab.ui.control.Button
        DiodeCurrentEditField           matlab.ui.control.NumericEditField
        DiodeCurrentEditFieldLabel      matlab.ui.control.Label
        DiodeTempEditField              matlab.ui.control.NumericEditField
        DiodeTempEditFieldLabel         matlab.ui.control.Label
        TurnDiodeOnButton               matlab.ui.control.StateButton
        StatusHistoryLabel              matlab.ui.control.Label
        StatusHistoryTextArea           matlab.ui.control.TextArea
        DiodeDataTextArea               matlab.ui.control.TextArea
        AdditionalSettingsTab           matlab.ui.container.Tab
        AdditionalDiodeSettingsPanel    matlab.ui.container.Panel
        SetButton                       matlab.ui.control.Button
        GateEditField                   matlab.ui.control.NumericEditField
        GateEditFieldLabel              matlab.ui.control.Label
        COMPortSettingsPanel            matlab.ui.container.Panel
        BaudrateDropDownLabel           matlab.ui.control.Label
        BaudrateDropDown                matlab.ui.control.DropDown
        SelectCOMPortDropDown           matlab.ui.control.DropDown
        SelectCOMPortDropDownLabel      matlab.ui.control.Label
        OpenPortButton                  matlab.ui.control.Button
    end

    
    properties (Access = private)
        port; % global serialport object
        baudrate = 9600; % Change baudrate here to connect
        terminator = 59; % Terminator for the serial port. For J20 laser the terminator is 59, representing ";" in ascii
        updating = false;
        t; % Timer object
    end
    
    methods (Access = private)
        
        function ReadLaserStatus(app, ~, ~)
            ports = serialportlist;
                if (~isempty(ports))
                    flush(app.port);
                    writeline(app.port, "?X11;?X21;?X31;?X41;?X51;?X61;?P;?C1;?T1;?Q;?G;?H"); % serial number
                    serialNumber = readline(app.port);
                    shipDate = readline(app.port);
                    opTemp = readline(app.port);
                    curLimit = readline(app.port);
                    initCurrent = readline(app.port);
                    opHours = readline(app.port);
                    laserPower = readline(app.port);
                    diodeCurrent = readline(app.port);
                    diodeTemp = readline(app.port);
                    repRate = readline(app.port);
                    gate = readline(app.port);
                    
                    app.DiodeDataTextArea.Value = [
                        strcat("Serial Number: ", serialNumber)
                        newline
                        strcat("Shipping Date: ", shipDate)
                        newline
                        strcat("Operating Temperature: ", opTemp)
                        newline
                        strcat("Current Limit: ", curLimit)
                        newline
                        strcat("Initial Current: ", initCurrent)
                        newline
                        strcat("Diode Hours: ", opHours)
                        newline
                        strcat("Laser Power: ", laserPower)
                        newline
                        strcat("Diode Current:", diodeCurrent)
                        newline
                        strcat("Diode Temperature: ", diodeTemp)
                        newline
                        strcat("Rep Rate: ", repRate)
                        newline
                        strcat("Gate: ", gate);
                    ];

                    app.Updates.Value = "Ready for user input";
    
                    app.StatusHistoryTextArea.Value = num2str(int8(char(readline(app.port))));

                    if isempty(shipDate) || isempty(ports)
                        app.Updates.Value = "Unable to read some or all data from laser. Please check the connection and try again.";
                        stop(app.t);
                    end

                    if length(char(serialNumber)) ~= 16
                        app.Updates.Value = "The values being returned from the laser are not in the appropriate order. Please restart the laser.";
                        stop(app.t);
                    end
                else
                    app.Updates.Value = "No COM ports detected.";
                    stop(app.t);
                end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            ports = serialportlist;

            if (~isempty(ports) && length(ports) == 1)
                app.SelectCOMPortDropDown.Items = [ports(1)];
                OpenPortButtonPushed(app);
                app.SelectCOMPortDropDown.Enable = "off";
                app.OpenPortButton.Enable = "off";
                app.BaudrateDropDown.Enable = "off";
            elseif (~isempty(ports) && length(ports) > 1)
                app.SelectCOMPortDropDown.Items = [ports(1)];
                for i = 2:length(ports)
                    app.SelectCOMPortDropDown.Items = [app.SelectCOMPortDropDown.Items ports(i)];
                end
                app.Updates.Value = "Multiple COM ports detected. Please go to the COM Settings tab and choose the appropriate port.";
            else
                app.OpenPortButton.Enable = "off";
                app.TurnDiodeOnButton.Enable = "off";
                app.UpdateValuesButton.Enable = "off";
                app.SetButton.Enable = "off";
                app.Updates.Value = "No COM ports detected. Please check the connection and try again.";
                return;
            end
            
            % Check for config.mat
            file = load('config.mat');
            if (~isfile('config.mat'))
                app.Updates.Value = [
                    "No config file found. Values were set to the hard-coded defaults."
                    "Baudrate: 9600"
                    "Diode Current: 0A"
                    "Diode Temperature: 30C"
                    "Rep Rate: 0Hz"
                ];
                app.baudrate = 9600;
                app.DiodeCurrentEditField.Value = 0;
                app.DiodeTempEditField.Value = 30;
                app.RepRateEditField.Value = 0;
            else
                app.baudrate = file(1).baudRate;
                app.DiodeCurrentEditField.Value = file(1).diodeCurrent;
                app.DiodeTempEditField.Value = file(1).diodeTemp;
                app.RepRateEditField.Value = file(1).repRate;
            end
        end

        % Button pushed function: OpenPortButton
        function OpenPortButtonPushed(app, event)
            if isempty(app.port) || app.port == 0
                port = app.SelectCOMPortDropDown.Value;
    
                app.Updates.Value = "Attempting to read data from laser... This can take a while";
    
                app.port = serialport(port, app.baudrate);
                configureTerminator(app.port, app.terminator);
                app.port.Timeout = 1; % Set the timeout to three seconds, so if data is not read from the laser the error will display sooner
                flush(app.port);

                app.OpenPortButton.Text = "Close Port";
                app.t = timer("Name","LaserRead","Period",5,"BusyMode","drop","TimerFcn",@app.ReadLaserStatus,"ExecutionMode","fixedRate");
                start(app.t);
            else
                app.port = 0;
                app.OpenPortButton.Text = "Open Port";
            end
        end

        % Button pushed function: UpdateValuesButton
        function UpdateValuesButtonPushed(app, event)
            app.updating = true;
            pause(1); % Safeguard to make sure the check loop does not interfere with the readings in this function
            if (app.TurnDiodeOnButton.Value == 1)
                resp = questdlg("The diode is currently on. Are you sure you want to send these commands?", "Update Vaues?");
                if resp ~= "Yes"
                    msgbox("The values were not updated.", "modal");
                    return;
                end
            end
            
            newDiodeCurrent = app.DiodeCurrentEditField.Value * 100;
            writeline(app.port, strcat('C1:', num2str(newDiodeCurrent)));
            pause(.5);
            % Laser does not store the diode current value, it can only be
            % read if the diode is turned on.
            
            newDiodeTemp = app.DiodeTempEditField.Value * 10;
            writeline(app.port, strcat('T1:', num2str(newDiodeTemp)));
            pause(.5);
            app.Updates.Value = "Attempting to set values...";

            newRepRate = app.RepRateEditField.Value;
            writeline(app.port, strcat('Q:', num2str(newRepRate)));
            pause(.5);
            writeline(app.port, "?Q");
            save("laser_config.mat", "newDiodeCurrent", "newDiodeTemp", "newRepRate", "-mat");
            val = readline(app.port);
            if ~strcmpi(replace(val, "Hz", ""), num2str(newRepRate))
                app.Updates.Value = "Could not set one or more values as defined by user";
            else
                app.Updates.Value = "Values have been set!";
            end
            pause(1)
            app.updating = false;
        end

        % Value changed function: TurnDiodeOnButton
        function TurnDiodeOnButtonValueChanged(app, event)
            value = app.TurnDiodeOnButton.Value;
            
            if (value == 0)
                %% Turn diode off
                app.TurnDiodeOnButton.Text = "Turn Diode On";
                app.Lamp.Color = "#999";

                writeline(app.port, "D0");
            else
                %% Turn diode on
                app.TurnDiodeOnButton.Text = "Turn Diode Off";
                app.Lamp.Color = "green";

                writeline(app.port, "D1");
            end
        end

        % Menu selected function: SaveconfigurationMenu
        function SaveconfigurationMenuSelected(app, event)
            resp = questdlg("Do you want to overwrite the current configuration file?", "Overwrite existing file?");

            if resp == "No"
                [fileName, folder] = uiputfile("*.mat");
                if ~isempty(fileName)
                    baudRate = app.baudrate;
                    diodeCurrent = app.DiodeCurrentEditField.Value;
                    diodeTemp = app.DiodeTempEditField.Value;
                    repRate = app.RepRateEditField.Value;

                    save(strcat(folder, "\", fileName), ...
                        "baudRate", ...
                        "diodeCurrent", ...
                        "diodeTemp", ...
                        "repRate", ...
                        "-mat" ...
                    );
                end
            elseif resp == "Yes"
                baudRate = app.baudrate;
                    diodeCurrent = app.DiodeCurrentEditField.Value;
                    diodeTemp = app.DiodeTempEditField.Value;
                    repRate = app.RepRateEditField.Value;

                    save("config.mat", ...
                        "baudRate", ...
                        "diodeCurrent", ...
                        "diodeTemp", ...
                        "repRate", ...
                        "-mat" ...
                    );
            end
        end

        % Menu selected function: LoadconfigurationMenu
        function LoadconfigurationMenuSelected(app, event)
           [file, folder] = uigetfile("*.mat");

           if ~isempty(file)
                info = load(strcat(folder, "\", file));
                app.baudrate = info(1).baudRate;
                app.DiodeCurrentEditField.Value = info(1).diodeCurrent;
                app.DiodeTempEditField.Value = info(1).diodeTemp;
                app.RepRateEditField.Value = info(1).repRate;
           end

           UpdateValuesButtonPushed(app);
        end

        % Button pushed function: SetButton
        function SetButtonPushed(app, event)
            writeline(app.port, strcat("G", num2str(app.GateEditField.Value)));
        end

        % Close request function: SpectraPhysicsJ20LaserUIFigure
        function SpectraPhysicsJ20LaserUIFigureCloseRequest(app, event)
            confirm = "Cancel";
            newConfig = "Cancel";
            if isfile("config.mat")
                confirm = questdlg("Do you want to overwrite the current config file?", "Overwrite current values?", "No");
            else
                newConfig = questdlg("Do you want to create a configuration file?", "Create config file?", "Yes");
            end

            if confirm == "Yes" || newConfig == "Yes"
                baudRate = app.baudrate;
                diodeCurrent = app.DiodeCurrentEditField.Value;
                diodeTemp = app.DiodeTempEditField.Value;
                repRate = app.RepRateEditField.Value;

                save("config.mat", ...
                    "baudRate", ...
                    "diodeCurrent", ...
                    "diodeTemp", ...
                    "repRate", ...
                    "-mat" ...
                );
            end

            if confirm == "Yes" || confirm == "No" || newConfig == "Yes" || newConfig == "No"
                if app.TurnDiodeOnButton.Value == 1
                    app.TurnDiodeOnButton.Value = 0;
                    TurnDiodeOnButtonValueChanged(app);
                end
                delete(app)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SpectraPhysicsJ20LaserUIFigure = uifigure('Visible', 'off');
            app.SpectraPhysicsJ20LaserUIFigure.Name = 'Spectra Physics J20 Laser';
            app.SpectraPhysicsJ20LaserUIFigure.CloseRequestFcn = createCallbackFcn(app, @SpectraPhysicsJ20LaserUIFigureCloseRequest, true);
            app.FileMenu = uimenu(app.SpectraPhysicsJ20LaserUIFigure);
            app.FileMenu.Text = 'File';
            app.SaveconfigurationMenu = uimenu(app.FileMenu);
            app.SaveconfigurationMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveconfigurationMenuSelected, true);
            app.SaveconfigurationMenu.Text = 'Save configuration';
            app.LoadconfigurationMenu = uimenu(app.FileMenu);
            app.LoadconfigurationMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadconfigurationMenuSelected, true);
            app.LoadconfigurationMenu.Text = 'Load configuration';
            app.TabGroup = uitabgroup(app.SpectraPhysicsJ20LaserUIFigure);
            app.MainControlsTab = uitab(app.TabGroup);
            app.MainControlsTab.Title = 'Main Controls';
            app.DiodeDataPanel = uipanel(app.MainControlsTab);
            app.DiodeDataPanel.Title = 'Diode Data';
            app.DiodeDataTextArea = uitextarea(app.DiodeDataPanel);
            app.DiodeDataTextArea.Editable = 'off';
            app.StatusHistoryTextArea = uitextarea(app.DiodeDataPanel);
            app.StatusHistoryTextArea.Editable = 'off';
            app.StatusHistoryLabel = uilabel(app.DiodeDataPanel);
            app.StatusHistoryLabel.Text = 'Status History';
            app.TurnDiodeOnButton = uibutton(app.DiodeDataPanel, 'state');
            app.TurnDiodeOnButton.ValueChangedFcn = createCallbackFcn(app, @TurnDiodeOnButtonValueChanged, true);
            app.TurnDiodeOnButton.Text = 'Turn Diode On';
            app.SetValuesPanel = uipanel(app.DiodeDataPanel);
            app.SetValuesPanel.Title = 'Set Values';
            app.DiodeTempEditFieldLabel = uilabel(app.SetValuesPanel);
            app.DiodeTempEditFieldLabel.Text = 'Diode Temp';
            app.DiodeTempEditField = uieditfield(app.SetValuesPanel, 'numeric');
            app.DiodeTempEditField.Tooltip = {'In degrees Celsius.'};
            app.DiodeCurrentEditFieldLabel = uilabel(app.SetValuesPanel);
            app.DiodeCurrentEditFieldLabel.Text = 'Diode Current';
            app.DiodeCurrentEditField = uieditfield(app.SetValuesPanel, 'numeric');
            app.DiodeCurrentEditField.Tooltip = {'In Amps, maximum of 40'};
            app.UpdateValuesButton = uibutton(app.SetValuesPanel, 'push');
            app.UpdateValuesButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateValuesButtonPushed, true);
            app.UpdateValuesButton.Text = 'Update Values';
            app.RepRateEditFieldLabel = uilabel(app.SetValuesPanel);
            app.RepRateEditFieldLabel.Text = 'Rep Rate';
            app.RepRateEditField = uieditfield(app.SetValuesPanel, 'numeric');
            app.RepRateEditField.Limits = [0 10000];
            app.RepRateEditField.Tooltip = {'In hertz. 0-10000.'};
            app.Lamp = uilamp(app.DiodeDataPanel);
            app.AdditionalSettingsTab = uitab(app.TabGroup);
            app.AdditionalSettingsTab.Title = 'Additional Settings';
            app.COMPortSettingsPanel = uipanel(app.AdditionalSettingsTab);
            app.COMPortSettingsPanel.Title = 'COM Port Settings';
            app.OpenPortButton = uibutton(app.COMPortSettingsPanel, 'push');
            app.OpenPortButton.ButtonPushedFcn = createCallbackFcn(app, @OpenPortButtonPushed, true);
            app.OpenPortButton.Text = 'Open Port';
            app.SelectCOMPortDropDownLabel = uilabel(app.COMPortSettingsPanel);
            app.SelectCOMPortDropDownLabel.Text = 'Select COM Port';
            app.SelectCOMPortDropDown = uidropdown(app.COMPortSettingsPanel);
            app.SelectCOMPortDropDown.Items = {};
            app.SelectCOMPortDropDown.Value = {};
            app.BaudrateDropDown = uidropdown(app.COMPortSettingsPanel);
            app.BaudrateDropDown.Items = {'9600', '19200'};
            app.BaudrateDropDown.Value = '9600';
            app.BaudrateDropDownLabel = uilabel(app.COMPortSettingsPanel);
            app.BaudrateDropDownLabel.Text = 'Baudrate';
            app.AdditionalDiodeSettingsPanel = uipanel(app.AdditionalSettingsTab);
            app.AdditionalDiodeSettingsPanel.Title = 'Additional Diode Settings';
            app.GateEditFieldLabel = uilabel(app.AdditionalDiodeSettingsPanel);
            app.GateEditFieldLabel.Text = 'Gate';
            app.GateEditField = uieditfield(app.AdditionalDiodeSettingsPanel, 'numeric');
            app.GateEditField.Tooltip = {'0 or 1. 1 to lase'};
            app.SetButton = uibutton(app.AdditionalDiodeSettingsPanel, 'push');
            app.SetButton.ButtonPushedFcn = createCallbackFcn(app, @SetButtonPushed, true);
            app.SetButton.Text = 'Set';
            app.ProgramUpdatesPanel = uipanel(app.SpectraPhysicsJ20LaserUIFigure);
            app.ProgramUpdatesPanel.Title = 'Program Updates';
            app.Updates = uitextarea(app.ProgramUpdatesPanel);
            app.Updates.Editable = 'off';
            % Show the figure after all components are created
            app.SpectraPhysicsJ20LaserUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lasercontrol

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SpectraPhysicsJ20LaserUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SpectraPhysicsJ20LaserUIFigure)
        end
    end
end