classdef danielleaird_new < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        WaveplateControlUIFigure        matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        SavetomatMenu                   matlab.ui.container.Menu
        LoadConfugurationMenu           matlab.ui.container.Menu
        DevicesMenu                     matlab.ui.container.Menu
        CloseAllMenu                    matlab.ui.container.Menu
        RefreshMenu                     matlab.ui.container.Menu
        QueryPositionsMenu              matlab.ui.container.Menu
        GridLayout                      matlab.ui.container.GridLayout
        GridLayout2                     matlab.ui.container.GridLayout
        GridLayout3                     matlab.ui.container.GridLayout
        Device1Select                   matlab.ui.control.DropDown
        GridLayout6                     matlab.ui.container.GridLayout
        Device1Name                     matlab.ui.control.Label
        Device1Toggle                   matlab.ui.control.StateButton
        GridLayout4                     matlab.ui.container.GridLayout
        DefaultStepdegreesSpinnerLabel  matlab.ui.control.Label
        Device1Step                     matlab.ui.control.Spinner
        Device1StepLeft                 matlab.ui.control.Button
        Device1StepRight                matlab.ui.control.Button
        GridLayout5                     matlab.ui.container.GridLayout
        RotatetodegreesEditFieldLabel   matlab.ui.control.Label
        Device1Rotate                   matlab.ui.control.NumericEditField
        Device1Go                       matlab.ui.control.Button
        Device1Feedback                 matlab.ui.control.TextArea
        GridLayout2_2                   matlab.ui.container.GridLayout
        GridLayout3_2                   matlab.ui.container.GridLayout
        Device2Select                   matlab.ui.control.DropDown
        GridLayout7                     matlab.ui.container.GridLayout
        Device2Name                     matlab.ui.control.Label
        Device2Toggle                   matlab.ui.control.StateButton
        GridLayout4_2                   matlab.ui.container.GridLayout
        DefaultStepdegreesSpinnerLabel_2  matlab.ui.control.Label
        Device2Step                     matlab.ui.control.Spinner
        Device2StepLeft                 matlab.ui.control.Button
        Device2StepRight                matlab.ui.control.Button
        GridLayout5_2                   matlab.ui.container.GridLayout
        RotatetodegreesEditFieldLabel_2  matlab.ui.control.Label
        Device2Rotate                   matlab.ui.control.NumericEditField
        Device2Go                       matlab.ui.control.Button
        Device2Feedback                 matlab.ui.control.TextArea
        GridLayout2_3                   matlab.ui.container.GridLayout
        GridLayout3_3                   matlab.ui.container.GridLayout
        Device3Select                   matlab.ui.control.DropDown
        GridLayout8                     matlab.ui.container.GridLayout
        Device3Name                     matlab.ui.control.Label
        Device3Toggle                   matlab.ui.control.StateButton
        GridLayout4_3                   matlab.ui.container.GridLayout
        DefaultStepdegreesSpinnerLabel_3  matlab.ui.control.Label
        Device3Step                     matlab.ui.control.Spinner
        Device3StepLeft                 matlab.ui.control.Button
        Device3StepRight                matlab.ui.control.Button
        GridLayout5_3                   matlab.ui.container.GridLayout
        RotatetodegreesEditFieldLabel_3  matlab.ui.control.Label
        Device3Rotate                   matlab.ui.control.NumericEditField
        Device3Go                       matlab.ui.control.Button
        Device3Feedback                 matlab.ui.control.TextArea
    end

    % ------------------------------------------------------ %
    %                                                        %
    %  App created by Isaac Maddox, Summer 2022 AFRL Intern  %
    %                                                        %
    % ------------------------------------------------------ %

    properties (Access = private)
        PSG_HWP;
        PSA_QWP;
        PSA_HWP;

        % Device COM ports
        port_PSG_HWP = "COM6";
        port_PSA_QWP = "COM5";
        port_PSA_HWP = "COM3";
    end
    
    methods (Access = private)
        function errorMessage(app, device, msg) % set an error message in a device feedback area; app, int, str
            switch (device)
                case 1
                    app.Device1Feedback.FontColor = 'red';
                    app.Device1Feedback.Value = msg;
                case 2
                    app.Device2Feedback.FontColor = 'red';
                    app.Device2Feedback.Value = msg;
                case 3
                    app.Device3Feedback.FontColor = 'red';
                    app.Device3Feedback.Value = msg;
            end
        end

        function dispMessage(app, device, msg) % set a neutral message in a device feedback area; app, int, str
            switch (device)
                case 1
                    app.Device1Feedback.FontColor = 'black';
                    app.Device1Feedback.Value = msg;
                case 2
                    app.Device2Feedback.FontColor = 'black';
                    app.Device2Feedback.Value = msg;
                case 3
                    app.Device3Feedback.FontColor = 'black';
                    app.Device3Feedback.Value = msg;
            end
        end

        function warnMessage(app, device, msg) % set a neutral message in a device feedback area; app, int, str
            switch (device)
                case 1
                    app.Device1Feedback.FontColor = '#E49700';
                    app.Device1Feedback.Value = msg;
                case 2
                    app.Device2Feedback.FontColor = '#E49700';
                    app.Device2Feedback.Value = msg;
                case 3
                    app.Device3Feedback.FontColor = '#E49700';
                    app.Device3Feedback.Value = msg;
            end
        end
        
        function ret = getESPPos(app, device)
            writeline(device, "1PA?");
            ret = str2double(readline(device));
        end
        
        function moveESPAbs(app, device, pos, deviceNum)
            while pos > 360
                pos = pos - 360;
            end
            while pos < 0
                pos = pos + 360;
            end
            
            dispMessage(app, deviceNum, strcat("Moving to ", num2str(pos)));
            cmd = ['1PA' num2str(pos)];
            writeline(device, cmd);
            moving = "P";
            while (moving == "P")
                writeline(device, "1TX?");
                moving = readline(device);
                pause(1)
            end
            dispMessage(app, deviceNum, ["Move complete." num2str(pos)])
        end
        
        %% Further commands written by Jarrod Brown
        
        function ret = getControllerState(app, s)
            writeCmd(app, s,'1TS','?');
            ret = readline(s);
        end

        function ret = writeCmd(app, s,cmd,val)
            val_str = num2str(val);
            str = string([cmd val_str]);
            writeline(s,str);
            ret = 1;
        end
        
        function move(app, s, state, device)
            originalState = state;
            commandedPos = getVal(app, s,'1TH');
            previousPos = NaN;
            dispMessage(app, device, ['Moving to ' num2str(commandedPos)])
            for i = 1:40 % check for move
                try
                    currentState = getControllerState(app, s);
                    if currentState == originalState
                        currentPos = getVal(app, s, '1TP');
                        if currentPos == previousPos
                            dispMessage(app, device, ["Move complete. " num2str(currentPos)]);
                            break
                        end
                        previousPos = currentPos;
                    end
                catch
                    warning('Cannot read postion') % #BOOKMARK
                end
                pause(.2);
            end
        end
        
        function moveAbsolute(app, s,val, device)
            originalState = getControllerState(app, s);
            writeCmd(app, s,'1PA',val);
            move(app, s,originalState, device);
        end
        
        function retVal = getVal(app, s, cmd)
            str = getChar(app, s,cmd);
            try
                retVal = str2double(str);
            catch
                warning('No return')
                retVal = NaN;
            end
        end
        
        function retChar = getChar(app, s,cmd)
            writeCmd(app, s,cmd,'?');
            ret = readline(s);
            try
                retChar = char(ret);
                retChar = retChar(4:end);
            catch
                warning('No return')
            end
        end
        
        function moveRelative(app, s,val, device)
            originalState = getControllerState(app, s);
            writeCmd(app, s,'1PR',val);  
            move(app, s,originalState, device);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            ports = serialportlist;
            if (isempty(ports)) % Don't run the code if no ports are available
                warnMessage(app, 1, "No device found. Check the connection to the device and refresh the device list.");
                warnMessage(app, 2, "No device found. Check the connection to the device and refresh the device list.");
                warnMessage(app, 3, "No device found. Check the connection to the device and refresh the device list.");
            else
                for i = 1:length(ports)
                    app.Device1Select.Items = [app.Device1Select.Items ports(i)];
                    app.Device2Select.Items = [app.Device2Select.Items ports(i)];
                    app.Device3Select.Items = [app.Device3Select.Items ports(i)];
                end
            end

            app.Device1StepLeft.Enable = 0;
            app.Device1StepRight.Enable = 0;
            app.Device1Go.Enable = 0;

            app.Device2StepLeft.Enable = 0;
            app.Device2StepRight.Enable = 0;
            app.Device2Go.Enable = 0;

            app.Device3StepLeft.Enable = 0;
            app.Device3StepRight.Enable = 0;
            app.Device3Go.Enable = 0;
        end

        % Menu selected function: SavetomatMenu
        function SavetomatMenuSelected(app, event)
            if (~isempty(app.PSG_HWP) && app.PSG_HWP ~= 0) device1Rotate = getVal(app, app.PSG_HWP, '1TP'); else device1Rotate = 0; end
            % TODO: replace app.placeholder with actual varible.
            if (~isempty(app.PSA_HWP) && app.PSA_HWP ~= 0) device2Rotate = getESPPos(app, app.PSA_HWP); else device2Rotate = 0; end
            if (~isempty(app.PSA_QWP) && app.PSA_QWP ~= 0) device3Rotate = getVal(app, app.PSA_QWP, '1TP'); else device3Rotate = 0; end
            device1Step = app.Device1Step.Value;
            device2Step = app.Device2Step.Value;
            device3Step = app.Device3Step.Value;
            device1GoTo = app.Device1Rotate.Value;
            device2GoTo = app.Device2Rotate.Value;
            device3GoTo = app.Device3Rotate.Value;
            device1Port = app.port_PSG_HWP;
            device2Port = app.port_PSA_HWP;
            device3Port = app.port_PSA_QWP;
            device1Open = ~isempty(app.PSG_HWP) && app.PSG_HWP ~= 0;
            device2Open = ~isempty(app.PSA_HWP) && app.PSA_HWP ~= 0;
            device3Open = ~isempty(app.PSA_QWP) && app.PSA_QWP ~= 0;

            [file, path] = uiputfile("*.mat");

            if ~isequal(file, 0) && ~isequal(path, 0) % The user actually selected a file
                try
                save(strcat(path, "\", file), ...
                    "device1Rotate", ...
                    "device2Rotate", ...
                    "device3Rotate", ...
                    "device1Step", ...
                    "device2Step", ...
                    "device3Step", ...
                    "device1GoTo", ...
                    "device2GoTo", ...
                    "device3GoTo", ...
                    "device1Port", ...
                    "device2Port", ...
                    "device3Port", ...
                    "device1Open", ...
                    "device2Open", ...
                    "device3Open", ...
                    '-mat'); % Save the values to a .mat file
                catch
                    msgbox("Could not save to file, please try again");
                end
            end
        end

        % Menu selected function: LoadConfugurationMenu
        function LoadConfugurationMenuSelected(app, event)
            CloseAllMenuSelected(app);
            %% Load values from selected mat file

            [fileSel, filePath] = uigetfile("*.mat"); % Open file selector with a restriction to only .mat files
            if ~isequal(fileSel, 0) % If the user selected a file
                try
                    info = load(strcat(filePath, "\", fileSel)); % Load information from the .mat file
                    % Set the values of the number fields
                    app.Device1Step.Value = info(1).device1Step;
                    app.Device2Step.Value = info(1).device2Step;
                    app.Device3Step.Value = info(1).device3Step;

                    app.Device1Rotate.Value = info(1).device1GoTo;
                    app.Device2Rotate.Value = info(1).device2GoTo;
                    app.Device3Rotate.Value = info(1).device3GoTo;

                    app.Device1Select.Value = info(1).device1Port;
                    app.Device2Select.Value = info(1).device2Port;
                    app.Device3Select.Value = info(1).device3Port;

                    %% #todo: rotate all three devices to their positions defined by the loaded file
                    
                    if (info(1).device1Open)
                        Device1ToggleValueChanged(app);
                        app.Device1Toggle.Value = 1;
                        moveAbsolute(app, app.PSG_HWP, info(1).device1Rotate, 1);
                    end
                    if (info(1).device2Open)
                        Device2ToggleValueChanged(app);
                        app.Device2Toggle.Value = 1;
                        moveESPAbs(app, app.PSA_HWP, info(1).device2Rotate, 2);
                    end
                    if (info(1).device3Open)
                        Device3ToggleValueChanged(app);
                        app.Device3Toggle.Value = 1;
                        moveAbsolute(app, app.PSA_QWP, info(1).device3Rotate, 3);
                    end
                catch err
                    msgbox('Could not load file, please try again');
                    disp(err);
                end
            end
        end

        % Button pushed function: Device1StepLeft
        function Device1StepLeftButtonPushed(app, event)
            % Negative increments on PSG_HWP
            if (~isempty(app.PSG_HWP))
                inc = app.Device1Step.Value * -1;
                moveRelative(app, app.PSG_HWP, inc, 1)
            end
        end

        % Menu selected function: CloseAllMenu
        function CloseAllMenuSelected(app, event)
            app.PSG_HWP = 0;
            app.PSA_QWP = 0;
            app.PSA_HWP = 0;

            app.Device1Feedback.Value = "Closed device.";
            app.Device2Feedback.Value = "Closed device.";
            app.Device3Feedback.Value = "Closed device.";

            app.Device1Toggle.Text = "Open";
            app.Device2Toggle.Text = "Open";
            app.Device3Toggle.Text = "Open";
            app.Device1Toggle.Value = 0;
            app.Device2Toggle.Value = 0;
            app.Device3Toggle.Value = 0;

            app.Device1StepLeft.Enable = 0;
            app.Device1StepRight.Enable = 0;
            app.Device1Go.Enable = 0;

            app.Device2StepLeft.Enable = 0;
            app.Device2StepRight.Enable = 0;
            app.Device2Go.Enable = 0;

            app.Device3StepLeft.Enable = 0;
            app.Device3StepRight.Enable = 0;
            app.Device3Go.Enable = 0;
        end

        % Close request function: WaveplateControlUIFigure
        function WaveplateControlUIFigureCloseRequest(app, event)
            clear app.PSG_HWP
            clear app.PSG_QWP
            clear app.placeholder;

            delete(app)
        end

        % Button pushed function: Device1StepRight
        function Device1StepRightButtonPushed(app, event)
            % Positive increments on PSG_HWP

            inc = app.Device1Step.Value;
            moveRelative(app, app.PSG_HWP, inc, 1);
        end

        % Button pushed function: Device1Go
        function Device1GoButtonPushed(app, event)
            target = app.Device1Rotate.Value;

            moveAbsolute(app, app.PSG_HWP, target, 1);
        end

        % Menu selected function: QueryPositionsMenu
        function QueryPositionsMenuSelected(app, event)
            if (~isempty(app.PSG_HWP) && app.PSG_HWP ~= 0)
                position = getVal(app, app.PSG_HWP, '1TP');
                app.Device1Feedback.Value = num2str(position);
            end
            if (~isempty(app.PSA_QWP) && app.PSA_QWP ~= 0)
                position = getVal(app, app.PSA_QWP, '1TP');
                app.Device3Feedback.Value = num2str(position);
            end
            if (~isempty(app.PSA_HWP) && app.PSA_HWP ~= 0)
                position = getESPPos(app, app.PSA_HWP);
                app.Device2Feedback.Value = num2str(position);
            end
        end

        % Menu selected function: RefreshMenu
        function RefreshMenuSelected(app, event)
            if (isempty(app.PSG_HWP) || app.PSG_HWP == 0) && (isempty(app.PSA_HWP) || app.PSA_HWP == 0) && (isempty(app.PSA_QWP) || app.PSA_QWP == 0)
               % Refresh devices
               ports = serialportlist;
                app.Device1Select.Items = [ports(1)];
                app.Device2Select.Items = [ports(1)];
                app.Device3Select.Items = [ports(1)];
                
                for i = 2:length(ports)
                    app.Device1Select.Items = [app.Device1Select.Items ports(i)];
                    app.Device2Select.Items = [app.Device2Select.Items ports(i)];
                    app.Device3Select.Items = [app.Device3Select.Items ports(i)];
                end
  
    
                dispMessage(app, 1, "Device list refreshed.");
                dispMessage(app, 2, "Device list refreshed.");
                dispMessage(app, 3, "Device list refreshed."); 
            else
               msgbox("Please close all devices before refreshing device list.", "modal"); 
            end
        end

        % Button pushed function: Device3StepLeft
        function Device3StepLeftButtonPushed(app, event)
            % Negative increments

            inc = app.Device3Step.Value * -1;
            moveRelative(app, app.PSA_QWP, inc, 3);

        end

        % Button pushed function: Device3StepRight
        function Device3StepRightButtonPushed(app, event)
            % Positive Increments

            inc = app.Device3Step.Value;
            moveRelative(app, app.PSA_QWP, inc, 3);
        end

        % Button pushed function: Device3Go
        function Device3GoButtonPushed(app, event)
            target = app.Device3Rotate.Value;

            moveAbsolute(app, app.PSA_QWP, target, 3);
        end

        % Value changed function: Device1Toggle
        function Device1ToggleValueChanged(app, event)
            if (isempty(app.PSG_HWP) || app.PSG_HWP == 0)
                port = app.Device1Select.Value;
                try
                    app.PSG_HWP = serialport(port, 921600);
                catch
                    errorMessage(app, 1, strcat(port, " is already in use."));
                    app.Device1Toggle.Value = 0;
                    return;
                end
                configureTerminator(app.PSG_HWP, "CR/LF");
                dispMessage(app, 1, strcat("Initializing on ", port));
                writeline(app.PSG_HWP, "1OR");
                try
                    for i = 1:20
                        state = getControllerState(app, app.PSG_HWP);
                        if strcmpi(state, '1TS000032') || strcmpi(state, '1TS000033')
                            dispMessage(app, 1, strcat("Initialized. Ready to use on ", port));
                            app.Device1Toggle.Text = "Close";
                            app.Device1StepLeft.Enable = 1;
                            app.Device1StepRight.Enable = 1;
                            app.Device1Go.Enable = 1;
                            break;
                        end
                        pause(.5);
                    end
                catch
                    errorMessage(app, 1, "Unable to open device.");
                    app.PSG_HWP = 0;
                end
            else
                app.PSG_HWP = 0;
                dispMessage(app, 1, "Closed Device");
                app.Device1Toggle.Text = "Open";
                app.Device1StepLeft.Enable = 0;
                app.Device1StepRight.Enable = 0;
                app.Device1Go.Enable = 0;
            end
        end

        % Value changed function: Device3Toggle
        function Device3ToggleValueChanged(app, event)
            value = app.Device3Toggle.Value;
            if (isempty(app.PSA_QWP) || app.PSA_QWP == 0)
                port = app.Device3Select.Value;
                try
                    app.PSA_QWP = serialport(port, 921600);
                catch
                    errorMessage(app, 3, strcat(port, " is already in use."));
                    app.Device1Toggle.Value = 0;
                    return;
                end
                configureTerminator(app.PSA_QWP, "CR/LF");
                dispMessage(app, 3, strcat("Initializing on ", port));
                writeline(app.PSA_QWP, "1OR");
                try
                    for i = 1:20
                        state = getControllerState(app, app.PSA_QWP);
                        if strcmpi(state, '1TS000032') || strcmpi(state, '1TS000033')
                            dispMessage(app, 3, strcat("Initialized. Ready to use on ", port));
                            app.Device3Toggle.Text = "Close";
                            app.Device3StepLeft.Enable = 1;
                            app.Device3StepRight.Enable = 1;
                            app.Device3Go.Enable = 1;
                            break;
                        end
                        pause(.5);
                    end
                catch
                    errorMessage(app, 3, "Unable to open device.");
                    app.PSA_QWP = 0;
                end
            else
                app.PSA_QWP = 0;
                dispMessage(app, 3, "Closed Device");
                app.Device3Toggle.Text = "Open";
                app.Device3StepLeft.Enable = 0;
                app.Device3StepRight.Enable = 0;
                app.Device3Go.Enable = 0;
            end
        end

        % Value changed function: Device1Select
        function Device1SelectValueChanged(app, event)
            value = app.Device1Select.Value;
            
            app.port_PSG_HWP = value;
        end

        % Value changed function: Device3Select
        function Device3SelectValueChanged(app, event)
            value = app.Device3Select.Value;
            
            app.port_PSA_QWP = value;
        end

        % Value changed function: Device2Select
        function Device2SelectValueChanged(app, event)
            value = app.Device2Select.Value;
            app.port_PSA_HWP = value;
        end

        % Button pushed function: Device2Go
        function Device2GoButtonPushed(app, event)
            pos = app.Device2Rotate.Value;
            moveESPAbs(app, app.PSA_HWP, pos, 2);
        end

        % Value changed function: Device2Toggle
        function Device2ToggleValueChanged(app, event)

            value = app.Device2Toggle.Value;
            
            if (isempty(app.PSA_HWP) || app.PSA_HWP == 0)
                app.Device2Feedback.Value = "Initializing...";
                app.PSA_HWP = serialport(app.Device2Select.Value, 19200);
                configureTerminator(app.PSA_HWP, "CR/LF");
                writeline(app.PSA_HWP, "1MO?");
                on = readline(app.PSA_HWP);
                if (on == "0")
                    writeline(app.PSA_HWP, "1MO");
                end
                    writeline(app.PSA_HWP, "1OR2");
                dispMessage(app, 2, strcat("Initialized. Ready to use on ", app.Device2Select.Value));
                
                app.Device2Toggle.Text = "Close";
                app.Device2StepLeft.Enable = 1;
                app.Device2StepRight.Enable = 1;
                app.Device2Go.Enable = 1;
            else
                app.PSA_HWP = 0;
                app.Device2Toggle.Text = "Open";
                app.Device2StepLeft.Enable = 0;
                app.Device2StepRight.Enable = 0;
                app.Device2Go.Enable = 0;
                app.Device2Feedback.Value = "Closed Device";
            end
        end

        % Button pushed function: Device2StepRight
        function Device2StepRightButtonPushed(app, event)
            % Positive Increments
            currentPos = getESPPos(app, app.PSA_HWP);
            newPos = currentPos + app.Device2Step.Value;
            moveESPAbs(app, app.PSA_HWP, newPos, 2);
        end

        % Button pushed function: Device2StepLeft
        function Device2StepLeftButtonPushed(app, event)
            % Negative Increments
            currentPos = getESPPos(app, app.PSA_HWP);
            newPos = currentPos - app.Device2Step.Value;
            moveESPAbs(app, app.PSA_HWP, newPos, 2);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.WaveplateControlUIFigure = uifigure('Visible', 'off');
            app.WaveplateControlUIFigure.Name = 'Waveplate Control';
            app.WaveplateControlUIFigure.CloseRequestFcn = createCallbackFcn(app, @WaveplateControlUIFigureCloseRequest, true);
            app.FileMenu = uimenu(app.WaveplateControlUIFigure);
            app.FileMenu.Text = 'File';
            app.SavetomatMenu = uimenu(app.FileMenu);
            app.SavetomatMenu.MenuSelectedFcn = createCallbackFcn(app, @SavetomatMenuSelected, true);
            app.SavetomatMenu.Tooltip = {'Safe configuration to a text file to load it later'};
            app.SavetomatMenu.Accelerator = 'S';
            app.SavetomatMenu.Text = 'Save to .mat';
            app.LoadConfugurationMenu = uimenu(app.FileMenu);
            app.LoadConfugurationMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadConfugurationMenuSelected, true);
            app.LoadConfugurationMenu.Accelerator = 'O';
            app.LoadConfugurationMenu.Text = 'Load Confuguration';
            app.DevicesMenu = uimenu(app.WaveplateControlUIFigure);
            app.DevicesMenu.Text = 'Devices';
            app.CloseAllMenu = uimenu(app.DevicesMenu);
            app.CloseAllMenu.MenuSelectedFcn = createCallbackFcn(app, @CloseAllMenuSelected, true);
            app.CloseAllMenu.Text = 'Close All';
            app.RefreshMenu = uimenu(app.DevicesMenu);
            app.RefreshMenu.MenuSelectedFcn = createCallbackFcn(app, @RefreshMenuSelected, true);
            app.RefreshMenu.Text = 'Refresh';
            app.QueryPositionsMenu = uimenu(app.DevicesMenu);
            app.QueryPositionsMenu.MenuSelectedFcn = createCallbackFcn(app, @QueryPositionsMenuSelected, true);
            app.QueryPositionsMenu.Text = 'Query Positions';
            app.GridLayout = uigridlayout(app.WaveplateControlUIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.Device1Select = uidropdown(app.GridLayout3);
            app.Device1Select.Items = {};
            app.Device1Select.ValueChangedFcn = createCallbackFcn(app, @Device1SelectValueChanged, true);
            app.Device1Select.Value = {};
            app.GridLayout6 = uigridlayout(app.GridLayout3);
            app.Device1Name = uilabel(app.GridLayout6);
            app.Device1Name.Text = 'PSG HWP';
            app.Device1Toggle = uibutton(app.GridLayout6, 'state');
            app.Device1Toggle.ValueChangedFcn = createCallbackFcn(app, @Device1ToggleValueChanged, true);
            app.Device1Toggle.Text = 'Open';
            app.GridLayout4 = uigridlayout(app.GridLayout2);
            app.DefaultStepdegreesSpinnerLabel = uilabel(app.GridLayout4);
            app.DefaultStepdegreesSpinnerLabel.Text = 'Default Step (degrees)';
            app.Device1Step = uispinner(app.GridLayout4);
            app.Device1Step.Limits = [0 Inf];
            app.Device1StepLeft = uibutton(app.GridLayout4, 'push');
            app.Device1StepLeft.ButtonPushedFcn = createCallbackFcn(app, @Device1StepLeftButtonPushed, true);
            app.Device1StepLeft.Text = '<---';
            app.Device1StepRight = uibutton(app.GridLayout4, 'push');
            app.Device1StepRight.ButtonPushedFcn = createCallbackFcn(app, @Device1StepRightButtonPushed, true);
            app.Device1StepRight.Text = '--->';
            app.GridLayout5 = uigridlayout(app.GridLayout2);
            app.RotatetodegreesEditFieldLabel = uilabel(app.GridLayout5);
            app.RotatetodegreesEditFieldLabel.Text = 'Rotate to (degrees)';
            app.Device1Rotate = uieditfield(app.GridLayout5, 'numeric');
            app.Device1Go = uibutton(app.GridLayout5, 'push');
            app.Device1Go.ButtonPushedFcn = createCallbackFcn(app, @Device1GoButtonPushed, true);
            app.Device1Go.Text = 'Go To Position';
            app.Device1Feedback = uitextarea(app.GridLayout2);
            app.Device1Feedback.Editable = 'off';
            app.Device1Feedback.Value = {'Open device to start moving'};
            app.GridLayout2_2 = uigridlayout(app.GridLayout);
            app.GridLayout3_2 = uigridlayout(app.GridLayout2_2);
            app.Device2Select = uidropdown(app.GridLayout3_2);
            app.Device2Select.Items = {};
            app.Device2Select.ValueChangedFcn = createCallbackFcn(app, @Device2SelectValueChanged, true);
            app.Device2Select.Value = {};
            app.GridLayout7 = uigridlayout(app.GridLayout3_2);
            app.Device2Name = uilabel(app.GridLayout7);
            app.Device2Name.Text = 'PSA HWP';
            app.Device2Toggle = uibutton(app.GridLayout7, 'state');
            app.Device2Toggle.ValueChangedFcn = createCallbackFcn(app, @Device2ToggleValueChanged, true);
            app.Device2Toggle.Text = 'Open';
            app.GridLayout4_2 = uigridlayout(app.GridLayout2_2);
            app.DefaultStepdegreesSpinnerLabel_2 = uilabel(app.GridLayout4_2);
            app.DefaultStepdegreesSpinnerLabel_2.Text = 'Default Step (degrees)';
            app.Device2Step = uispinner(app.GridLayout4_2);
            app.Device2Step.Limits = [0 Inf];
            app.Device2StepLeft = uibutton(app.GridLayout4_2, 'push');
            app.Device2StepLeft.ButtonPushedFcn = createCallbackFcn(app, @Device2StepLeftButtonPushed, true);
            app.Device2StepLeft.Text = '<---';
            app.Device2StepRight = uibutton(app.GridLayout4_2, 'push');
            app.Device2StepRight.ButtonPushedFcn = createCallbackFcn(app, @Device2StepRightButtonPushed, true);
            app.Device2StepRight.Text = '--->';
            app.GridLayout5_2 = uigridlayout(app.GridLayout2_2);
            app.RotatetodegreesEditFieldLabel_2 = uilabel(app.GridLayout5_2);
            app.RotatetodegreesEditFieldLabel_2.Text = 'Rotate to (degrees)';
            app.Device2Rotate = uieditfield(app.GridLayout5_2, 'numeric');
            app.Device2Go = uibutton(app.GridLayout5_2, 'push');
            app.Device2Go.ButtonPushedFcn = createCallbackFcn(app, @Device2GoButtonPushed, true);
            app.Device2Go.Text = 'Go To Position';
            app.Device2Feedback = uitextarea(app.GridLayout2_2);
            app.Device2Feedback.Editable = 'off';
            app.Device2Feedback.Value = {'Open device to start moving'};
            app.GridLayout2_3 = uigridlayout(app.GridLayout);
            app.GridLayout3_3 = uigridlayout(app.GridLayout2_3);
            app.Device3Select = uidropdown(app.GridLayout3_3);
            app.Device3Select.Items = {};
            app.Device3Select.ValueChangedFcn = createCallbackFcn(app, @Device3SelectValueChanged, true);
            app.Device3Select.Value = {};
            app.GridLayout8 = uigridlayout(app.GridLayout3_3);
            app.Device3Name = uilabel(app.GridLayout8);
            app.Device3Name.Text = 'PSA QWP';
            app.Device3Toggle = uibutton(app.GridLayout8, 'state');
            app.Device3Toggle.ValueChangedFcn = createCallbackFcn(app, @Device3ToggleValueChanged, true);
            app.Device3Toggle.Text = 'Open';
            app.GridLayout4_3 = uigridlayout(app.GridLayout2_3);
            app.DefaultStepdegreesSpinnerLabel_3 = uilabel(app.GridLayout4_3);
            app.DefaultStepdegreesSpinnerLabel_3.Text = 'Default Step (degrees)';
            app.Device3Step = uispinner(app.GridLayout4_3);
            app.Device3Step.Limits = [0 Inf];
            app.Device3StepLeft = uibutton(app.GridLayout4_3, 'push');
            app.Device3StepLeft.ButtonPushedFcn = createCallbackFcn(app, @Device3StepLeftButtonPushed, true);
            app.Device3StepLeft.Text = '<---';
            app.Device3StepRight = uibutton(app.GridLayout4_3, 'push');
            app.Device3StepRight.ButtonPushedFcn = createCallbackFcn(app, @Device3StepRightButtonPushed, true);
            app.Device3StepRight.Text = '--->';
            app.GridLayout5_3 = uigridlayout(app.GridLayout2_3);
            app.RotatetodegreesEditFieldLabel_3 = uilabel(app.GridLayout5_3);
            app.RotatetodegreesEditFieldLabel_3.Text = 'Rotate to (degrees)';
            app.Device3Rotate = uieditfield(app.GridLayout5_3, 'numeric');
            app.Device3Go = uibutton(app.GridLayout5_3, 'push');
            app.Device3Go.ButtonPushedFcn = createCallbackFcn(app, @Device3GoButtonPushed, true);
            app.Device3Go.Text = 'Go To Position';
            app.Device3Feedback = uitextarea(app.GridLayout2_3);
            app.Device3Feedback.Editable = 'off';
            app.Device3Feedback.Value = {'Open device to start moving'};
            % Show the figure after all components are created
            app.WaveplateControlUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = danielleaird_new

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.WaveplateControlUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.WaveplateControlUIFigure)
        end
    end
end